classdef prueba1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        imagenBase             matlab.ui.control.Image
        CargaunaimagenButton   matlab.ui.control.Button
        DetectarobjetosButton  matlab.ui.control.Button
        ImagenProcesada        matlab.ui.control.Image
        SEMAFOROLampLabel      matlab.ui.control.Label
        SEMAFOROLamp           matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargaunaimagenButton
        function CargaunaimagenButtonPushed(app, event)
            
            startingFolder = 'C:\Program Files\MATLAB';
            defaultFileName = fullfile(startingFolder, '*.*');
            
            [file, filePath] = uigetfile(defaultFileName, 'Select a file');
            
            if file == 0
                % User clicked the Cancel button.
                return;
            end
            
            fullFileName = fullfile(filePath, file)
            myImage = imread(fullFileName);
            app.imagenBase = imagesc(myImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.imagenBase = uiimage(app.UIFigure);
            app.imagenBase.ImageSource = '59ca700f40-20170926-patxot-paso-peatones-invidentes-ciegos-667.jpg';
            app.CargaunaimagenButton = uibutton(app.UIFigure, 'push');
            app.CargaunaimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargaunaimagenButtonPushed, true);
            app.CargaunaimagenButton.Text = 'Carga una imagen';
            app.ImagenProcesada = uiimage(app.UIFigure);
            app.SEMAFOROLampLabel = uilabel(app.UIFigure);
            app.SEMAFOROLampLabel.Text = 'SEMAFORO';
            app.SEMAFOROLamp = uilamp(app.UIFigure);
            app.DetectarobjetosButton = uibutton(app.UIFigure, 'push');
            app.DetectarobjetosButton.Text = 'Detectar objetos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end